classdef xiao_ble_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        LampLabel             matlab.ui.control.Label
        ConnectStatusLamp     matlab.ui.control.Lamp
        RawDataTextAreaLabel  matlab.ui.control.Label
        RawDataTextArea       matlab.ui.control.TextArea
        RawDataLabel          matlab.ui.control.Label
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        CenterPanel           matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        ParsedPlotLabel       matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        FusedResultLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        BLEConnectCheckTimer = 0;
        isBLEConnectted = false; % BLE connect status
        BLEList = table;
        BLEName = table;
    end
    
    methods (Access = private)
        
        function BLEConnectCheckTimerCallback(app, obj, event)
            % Scan bluetooth devices
            app.BLEList = blelist;
            % Get the name string array from blelist result
            app.BLEName = app.BLEList.Name;
            % Remove empty device name
            is_BLE_name_empty = cellfun(@isempty, app.BLEName);
            app.BLEName = app.BLEName(~is_BLE_name_empty);
            % Append '---' device to device list, If user choose '---', it
            % will disconnect BLE device
            app.BLEName{end+1} = '---';
            app.BLEName = sort(app.BLEName);
            % Refresh bluetooth device list in drop down items
            app.DropDown.Items = app.BLEName;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set BLE initial connection status
            app.isBLEConnectted = false;
            app.ConnectStatusLamp.Color = [0.5 0.5 0.5];
            
            % Config timer
            % Refernce: https://ww2.mathworks.cn/matlabcentral/answers/346703-how-to-use-timer-callback-in-app-designer
            % Refresh BLE device list every 5 seconds
            app.BLEConnectCheckTimer = timer('Period',5,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.BLEConnectCheckTimer.TimerFcn = @app.BLEConnectCheckTimerCallback;
            
            % Start timer
            start(app.BLEConnectCheckTimer); % stop(app.BLEConnectCheckTimer);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {543, 543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {233, '1x', 290};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            if(app.isBLEConnectted == true )
                app.isBLEConnectted = false;
                app.ConnectStatusLamp.Color = [0.5 0.5 0.5];
            else
                app.isBLEConnectted = true;
                app.ConnectStatusLamp.Color = [0 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LampLabel = uilabel(app.LeftPanel);
            app.LampLabel.Visible = 'off';
            app.LampLabel.Text = 'Lamp';
            app.ConnectStatusLamp = uilamp(app.LeftPanel);
            app.ConnectStatusLamp.Tooltip = {'BLE connection status.'; 'Gray: No connection.'; 'Green: Connected.'; ''};
            app.RawDataTextAreaLabel = uilabel(app.LeftPanel);
            app.RawDataTextAreaLabel.Text = 'Raw Data';
            app.RawDataTextArea = uitextarea(app.LeftPanel);
            app.RawDataLabel = uilabel(app.LeftPanel);
            app.RawDataLabel.Text = 'Raw Data >>';
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'---'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'Available bluetooth list.'; 'Auto refresh every 5s.'; 'Choos --- will disconnect device.'};
            app.DropDown.Value = '---';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ParsedPlotLabel = uilabel(app.CenterPanel);
            app.ParsedPlotLabel.Text = 'Parsed Plot >>';
            app.RightPanel = uipanel(app.GridLayout);
            app.FusedResultLabel = uilabel(app.RightPanel);
            app.FusedResultLabel.Text = 'Fused Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xiao_ble_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end